classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.StateButton
        BotonExport  matlab.ui.control.Button
        BotonStop    matlab.ui.control.Button
        UITable      matlab.ui.control.Table
        BotonPlay    matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM6", 9600, "Timeout", 5);
        end

        % Button pushed function: BotonPlay
        function BotonPlayButtonPushed(app, event)

        end

        % Button pushed function: BotonStop
        function BotonStopButtonPushed(app, event)
            global parar
            parar = 1;
        end

        % Button pushed function: BotonExport
        function BotonExportButtonPushed(app, event)
            global datos
            writematrix(datos','datos.xls');
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            global parar
            parar = 0;
            k=1;
            while value == 1
                if parar == 1
                    break;
                else
                    muestra(k)=k;
                    lectura(k)= str2double(readline(app.s));
                    set(app.Label,'Text',num2str(lectura(k)));
                    stem(app.UIAxes,muestra,lectura);
                    k=k+1;
                    set(app.Button,'BackgroundColor',[0 0 1],'FontColor',[0 1 0],'Text','Sensando...');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BotonPlay = uibutton(app.UIFigure, 'push');
            app.BotonPlay.ButtonPushedFcn = createCallbackFcn(app, @BotonPlayButtonPushed, true);
            app.BotonPlay.Icon = fullfile(pathToMLAPP, 'boton-de-play.png');
            app.BotonPlay.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.BotonStop = uibutton(app.UIFigure, 'push');
            app.BotonStop.ButtonPushedFcn = createCallbackFcn(app, @BotonStopButtonPushed, true);
            app.BotonStop.Icon = fullfile(pathToMLAPP, 'detener.png');
            app.BotonStop.Text = '';
            app.BotonExport = uibutton(app.UIFigure, 'push');
            app.BotonExport.ButtonPushedFcn = createCallbackFcn(app, @BotonExportButtonPushed, true);
            app.BotonExport.Icon = fullfile(pathToMLAPP, 'exportar.png');
            app.BotonExport.Text = '';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end